classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        FFTSizeEditFieldLabel         matlab.ui.control.Label
        FFTSizeEditField              matlab.ui.control.NumericEditField
        hopSizeEditFieldLabel         matlab.ui.control.Label
        hopSizeEditField              matlab.ui.control.EditField
        BPMEditFieldLabel             matlab.ui.control.Label
        BPMEditField                  matlab.ui.control.NumericEditField
        loadaudioFileButton           matlab.ui.control.Button
        startrhytmicsanalyseButton    matlab.ui.control.Button
        QuantificationEditFieldLabel  matlab.ui.control.Label
        QuantificationEditField       matlab.ui.control.NumericEditField
        startfrequencyanalyseButton   matlab.ui.control.Button
        converttomidiFileButton       matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileName % the audio file name
        PathName %the path to the audio file
        MirAudioData %the audiodata mir object
        audioData %the audiodata
        audioTime %the vector of time
        Fs %sampling frequency
        beats %time of noir
        audioFiltered %the mir object for audio filtered
        audioFilteredData %the data of audio filtered
        audioEnvelope %the mir object for audio envelope
        audioEnvelopeData  %data of the envelope
        envelopeTime % the new time vector for the envelope
        envelopePeaks % the peaks in the envelope
        envelopeLocs %the position of the peaks
        nbrNotes % the number of notes detected
        notesSegmented %array of notes, audio segment
        notesDuration %array of notes durations
        midiFreq %the midiFreaquency
        midiMatrix
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadaudioFileButton
        function loadaudioFileButtonPushed(app, event)

            %get the audioFile
            [app.FileName, app.PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
            %load the audioData
            theAudioFile = strcat(app.PathName,app.FileName);
            app.MirAudioData = miraudio(theAudioFile,'Normal');
            [app.audioData, app.Fs] = audioread([app.PathName app.FileName]);
            app.audioData = app.audioData(:,1);
            
            %create the timeVector for the audioData
            N = length(app.audioData); % sample lenth
            app.audioTime = linspace(0, N/app.Fs, N);
            plot(app.UIAxes,app.audioTime,app.audioData);


        end

        % Button pushed function: startrhytmicsanalyseButton
        function startrhytmicsanalyseButtonPushed(app, event)
            app.beats = 1/(app.BPMEditField.Value/60);
            
            %filter the audioData
            app.audioFiltered = mirfilterbank(app.MirAudioData,'Gammatone','Channel',7); 
            app.audioFilteredData = mirgetdata(app.audioFiltered);
            plot(app.UIAxes2,app.audioTime,app.audioFilteredData);
            
            %take the envelope
            app.audioEnvelope = mirenvelope(app.audioFiltered, 'FilterType', 'IIR','HalfwaveCenter','Diff','Normal','Center');
            app.audioEnvelopeData = mirgetdata(app.audioEnvelope);
            data = get(app.audioEnvelope,'Time');
            app.envelopeTime = data{1,1}{:,1};
            
            %find peaks of the enveloppe, to plot
            [app.envelopePeaks,app.envelopeLocs] = findpeaks(app.audioEnvelopeData,app.envelopeTime,'MinPeakDistance',app.QuantificationEditField.Value,'MinPeakHeight',0.02);
            plot(app.UIAxes3,app.envelopeTime,app.audioEnvelopeData,app.envelopeLocs,app.envelopePeaks,'o');
             
            %the number of detected notes
            app.nbrNotes = length(app.envelopeLocs);
            
            %store the  segmented data
            for i =1:app.nbrNotes
                if i == app.nbrNotes
                    app.notesSegmented{i} = miraudio(app.MirAudioData,'Extract' , app.envelopeLocs(i),app.audioTime(length(app.audioTime)));
                    app.notesDuration(i) = 0.25;
                else
                    app.notesSegmented{i} = miraudio(app.MirAudioData,'Extract' ,app.envelopeLocs(i),app.envelopeLocs(i+1));
                    app.notesDuration(i) = app.envelopeLocs(i+1)-app.envelopeLocs(i);
                end
            end
        end

        % Button pushed function: startfrequencyanalyseButton
        function startfrequencyanalyseButtonPushed(app, event)
            for i =1:app.nbrNotes
                autocor = mirautocor(app.notesSegmented{i},'Enhanced','max',0.0125);
                peak = mirpeaks(autocor,'Only','Total',1);
                data = mirgetdata(peak);
                app.midiFreq(i,1) = round( hz2midi(1/data));
            end
            
            [FFTVector,band,newTime] = spectrogram (app.audioData,4096,2048,4096,app.Fs,'yaxis');     
            imagesc(app.UIAxes4, newTime, band, log(1+abs(FFTVector)) ); %plot the log spectrum
            set(app.UIAxes4,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            
        end

        % Button pushed function: converttomidiFileButton
        function converttomidiFileButtonPushed(app, event)
            app.midiMatrix(:,1) = app.envelopeLocs(:)/app.beats;
            app.midiMatrix(:,2) = app.notesDuration/app.beats;
            app.midiMatrix(:,3) = 1.0;
            app.midiMatrix(:,4) = app.midiFreq(:);
            app.midiMatrix(:,5) = 100;
            app.midiMatrix(:,6) = app.envelopeLocs(:);
            app.midiMatrix(:,7) = app.notesDuration(:);
            
            writemidi(app.midiMatrix,'miditest.mid',app.BPMEditField.Value);
            pianoroll(app.midiMatrix)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'audio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'audio filtred')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'envelope with peaks')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.FFTSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FFTSizeEditFieldLabel.Text = 'FFTSize';
            app.FFTSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.hopSizeEditFieldLabel = uilabel(app.UIFigure);
            app.hopSizeEditFieldLabel.Text = 'hopSize';
            app.hopSizeEditField = uieditfield(app.UIFigure, 'text');
            app.BPMEditFieldLabel = uilabel(app.UIFigure);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.loadaudioFileButton = uibutton(app.UIFigure, 'push');
            app.loadaudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadaudioFileButtonPushed, true);
            app.loadaudioFileButton.Text = 'load audioFile';
            app.startrhytmicsanalyseButton = uibutton(app.UIFigure, 'push');
            app.startrhytmicsanalyseButton.ButtonPushedFcn = createCallbackFcn(app, @startrhytmicsanalyseButtonPushed, true);
            app.startrhytmicsanalyseButton.Text = 'start rhytmics analyse';
            app.QuantificationEditFieldLabel = uilabel(app.UIFigure);
            app.QuantificationEditFieldLabel.Text = 'Quantification';
            app.QuantificationEditField = uieditfield(app.UIFigure, 'numeric');
            app.startfrequencyanalyseButton = uibutton(app.UIFigure, 'push');
            app.startfrequencyanalyseButton.ButtonPushedFcn = createCallbackFcn(app, @startfrequencyanalyseButtonPushed, true);
            app.startfrequencyanalyseButton.Text = 'start frequency analyse';
            app.converttomidiFileButton = uibutton(app.UIFigure, 'push');
            app.converttomidiFileButton.ButtonPushedFcn = createCallbackFcn(app, @converttomidiFileButtonPushed, true);
            app.converttomidiFileButton.Text = 'convert to midi File';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'spectrogram')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end